classdef RawatProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TimeDivSSpinner            matlab.ui.control.Spinner
        TimeDivSSpinnerLabel       matlab.ui.control.Label
        OffsetVEditField           matlab.ui.control.NumericEditField
        OffsetVEditFieldLabel      matlab.ui.control.Label
        PhaseDegreeEditField       matlab.ui.control.NumericEditField
        PhaseDegreeEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField       matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        AmplitudeVEditField        matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel   matlab.ui.control.Label
        WaveformDropDown           matlab.ui.control.DropDown
        WaveformDropDownLabel      matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        PlotButton                 matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        Switch                     matlab.ui.control.Switch
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                app.Lamp.Color = 'r';
                cla(app.UIAxes);
                app.AmplitudeVEditField.Value = 1;
                app.FrequencyHzEditField.Value = 1;
                app.PhaseDegreeEditField.Value = 0;
                app.OffsetVEditField.Value = 0;
            else
                app.Lamp.Color = 'g';
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a = app.AmplitudeVEditField.Value ;
            F = app.FrequencyHzEditField.Value;
            Td = app.TimeDivSSpinner.Value;
            %Vd = app.VoltsDivVEditField.Value;
            %T = Td*Vd;
            T = 1/F;
            b = (2*pi)/T;
            c = app.PhaseDegreeEditField.Value;
            d = app.OffsetVEditField.Value;
            value = app.Switch.Value;
            Wtype = app.WaveformDropDown.Value;
            if strcmp(value,'On') && strcmp(Wtype,'Sine')
                cla(app.UIAxes);
                x = 0:0.01: (360/180)*pi*Td;
                y = a*sin((b*x)+c)+d;
                plot(app.UIAxes,y);
                %zoom(app.UIAxes,'on');
             elseif strcmp(value,'On') && strcmp(Wtype,'Square')
                cla(app.UIAxes);
                sq = 0;
                K = 1000;
                N = 50;
                tt = 2*pi;
                t = (0:N)/tt;
                for k=1:2:K
                    sq = sq + (4*a*sin(k*b*t))/(pi*k);
                end
                plot(app.UIAxes,sq);
            elseif strcmp(value,'On') && strcmp(Wtype,'Triangular')
                cla(app.UIAxes);
                tri = 0;
                omega = 2*pi*F;
                K = 1000;
                t = (0:1/(2^20):Td);
                for k = 1:2:K
                    tri = tri + ((-4*a/(k*pi)^2)*cos(k*omega*t));
                end
                plot(app.UIAxes,tri);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {212, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WaveformDropDownLabel = uilabel(app.LeftPanel);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.LeftPanel);
            app.WaveformDropDown.Items = {'Sine', 'Square', 'Triangular'};
            app.WaveformDropDown.Value = 'Sine';
            app.AmplitudeVEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudeVEditField.Value = 1;
            app.FrequencyHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyHzEditField.Value = 1;
            app.PhaseDegreeEditFieldLabel = uilabel(app.LeftPanel);
            app.PhaseDegreeEditFieldLabel.Text = 'Phase (Degree)';
            app.PhaseDegreeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OffsetVEditFieldLabel = uilabel(app.LeftPanel);
            app.OffsetVEditFieldLabel.Text = 'Offset (V)';
            app.OffsetVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeDivSSpinnerLabel = uilabel(app.LeftPanel);
            app.TimeDivSSpinnerLabel.Text = 'Time / Div (S)';
            app.TimeDivSSpinner = uispinner(app.LeftPanel);
            app.TimeDivSSpinner.Limits = [1 10];
            app.TimeDivSSpinner.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Wave')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.RightPanel);
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RawatProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end